classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AM2_AT03UIFigure  matlab.ui.Figure
        FicheiroMenu      matlab.ui.container.Menu
        ExportarMenu      matlab.ui.container.Menu
        AjudaMenu         matlab.ui.container.Menu
        mSairMenu         matlab.ui.container.Menu
        SobreMenu         matlab.ui.container.Menu
        mRelatorioMenu    matlab.ui.container.Menu
        mAutores          matlab.ui.container.Menu
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        ResetButton       matlab.ui.control.Button
        bAtualizar        matlab.ui.control.Button
        bgMetodos         matlab.ui.container.ButtonGroup
        rbTodos           matlab.ui.control.RadioButton
        rbPM              matlab.ui.control.RadioButton
        rbODE45           matlab.ui.control.RadioButton
        rbRK4             matlab.ui.control.RadioButton
        rbRK2             matlab.ui.control.RadioButton
        rbEulerM          matlab.ui.control.RadioButton
        rbEuller          matlab.ui.control.RadioButton
        DadosPVI          matlab.ui.container.Panel
        eY0               matlab.ui.control.NumericEditField
        aLabel_4          matlab.ui.control.Label
        eN                matlab.ui.control.NumericEditField
        aLabel_3          matlab.ui.control.Label
        eB                matlab.ui.control.NumericEditField
        aLabel_2          matlab.ui.control.Label
        eA                matlab.ui.control.NumericEditField
        aLabel            matlab.ui.control.Label
        eF                matlab.ui.control.EditField
        ftyLabel          matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        UITable           matlab.ui.control.Table
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            
                syms y(t)
                strF = app.eF.Value;
                f = @(t,y) eval(vectorize(strF));
                a = app.eA.Value;
                b = app.eB.Value;
                n = app.eN.Value;
                y0 = app.eY0.Value;


                escolhabgMetodos = app.bgMetodos.SelectedObject;
                metodoSelecionado = find([app.rbEuller,...
                                         app.rbEulerM ...
                                         app.rbRK2 ...
                                         app.rbRK4 ...
                                         app.rbODE45 ...
                                         app.rbPM ...
                                         app.rbTodos]==escolhabgMetodos);


                erroFuncao = MException("MATLAB:bAtualizar:badF", ...
                                        "Introduza uma função em t e y");

                erroA = MException("MATLAB:bAtualizar:badA", ...
                                   "Introduza  um número real");
                
                erroB = MException("MATLAB:bAtualizar:badB", ...
                                   "Introduza um b > a");
                
                erroY0 = MException("MATLAB:bAtualizar:badY0", ...
                                   "Introduza  um número real");
                erroN = MException("MATLAB:bAtualizar:badN", ...
                                   "Introduza  um número real positivo " + ...
                                   "e maior ou igual a 2") ;

                
          try
                try
                    z = f(t,y);
                    app.eF.BackgroundColor = "w";
                catch 
                    app.eF.BackgroundColor = "r";
                    throw(erroFuncao);   
                
                end
                    
                
                if isscalar(a) && isreal(a)
                    app.eA.BackgroundColor = "w";
                else
                    app.eA.BackgroundColor = "r";
                    throw(erroA);
                end
                    
                
                if isscalar(b) && isreal(b) && b>a
                     app.eB.BackgroundColor = "w";
                else
                     app.eB.BackgroundColor = "r";
                     throw(erroB);
                end
                    
                 
                 if isscalar(n) && isreal(n) && (n>=2)
                      app.eN.BackgroundColor = "w";
                 else
                      app.eN.BackgroundColor = "r";
                      throw(erroN);
                 end
                 
                    
                 if isscalar(y0) && isreal(y0)
                      app.eY0.BackgroundColor = "w";
                 else
                      app.eY0.BackgroundColor = "r";
                      throw(erroY0);
                 end
                 

                yEuler = Euler(f,a,b,n,y0);
                yEulerM = EulerM(f,a,b,n,y0);
                yRK2 = RK2(f,a,b,n,y0);
                yRK4 = RK4(f,a,b,n, y0);
                yODE45 = ODE45 (f,a,b,n,y0);
                yPM = PM(f,a,b,n,y0);
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                
                g =@(t) eval(vectorize(char(sExata)));
                
                h = (b-a)/n;
                t = a:h:b;
                yExata = g(t);
               
                erroEuler = abs(yExata-yEuler);
                erroEulerM = abs(yExata-yEulerM);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroODE45 = abs(yExata-yODE45);
                erroPM = abs(yExata-yPM);
                
                
                plot(app.UIAxes,t, yExata,"-ro")
                hold (app.UIAxes,"on")


                switch metodoSelecionado
                    case 1
                        plot(app.UIAxes,t,yEuler,"-g*")
                        legenda = {'Exata','Euler'};
                        tabela = [t.', yExata.', yEuler.', erroEuler.'];
                        nomeColunas = {'t', 'Exata', 'Euler',  'ErroEuler'};
                    case 2
                        plot(app.UIAxes,t,yEulerM,"-m*")
                        legenda = {'Exata','EulerM'};
                        tabela = [t.', yExata.', yEulerM.', erroEulerM.'];
                        nomeColunas = {'t', 'Exata', 'EulerM',  'ErroEulerM'};
                    case 3
                        plot(app.UIAxes,t,yRK2,"-b+")
                        legenda = {'Exata','RK2'};
                        tabela = [t.', yExata.', yRK2.', erroRK2.'];
                        nomeColunas = {'t', 'Exata', 'RK2',  'ErroRK2'};
                    case 4
                        plot(app.UIAxes,t,yRK4,"-k+")
                        legenda = {'Exata','RK4'};
                        tabela = [t.', yExata.', yRK4.', erroRK4.'];
                        nomeColunas = {'t', 'Exata', 'RK4',  'ErroRK4'};
                    case 5
                        plot(app.UIAxes,t,yODE45,"-y+")
                        legenda = {'Exata','ODE45'};
                        tabela = [t.', yExata.', yODE45.', erroODE45.'];
                        nomeColunas =  {'t', 'Exata', 'ODE45',  'ErroODE45'};
                        
                     case 6
                        plot(app.UIAxes,t,yPM,"-c+")
                        legenda = {'Exata','PM'};
                        tabela = [t.', yExata.', yPM.', erroPM.'];
                        nomeColunas =  {'t', 'Exata', 'PM',  'ErroPM'};
                        
                     case 7
                         plot(app.UIAxes,t,yEuler,"-g*")
                         plot(app.UIAxes,t,yEulerM,"-m*")
                         plot(app.UIAxes,t,yRK2,"-b+")
                         plot(app.UIAxes,t,yRK4,"-k+")
                         plot(app.UIAxes,t,yODE45,"-y+")
                         plot(app.UIAxes,t,yPM,"-c+")
                         legenda =  {'Exata','Euler','EulerM','RK2','RK4','ODE45','PM'};
                         tabela = [t.', yExata.', yEuler.',yEulerM.', ...
                             yRK2.', yRK4.',yODE45.',yPM.', erroEuler.', erroEulerM.',... 
                             erroRK2.', erroRK4.',erroODE45.',erroPM.'];
                        nomeColunas = {'t', 'Exata', 'Euler', 'EulerM',...
                            'RK2', 'RK4','ODE45','PM', 'ErroEuler', 'ErroEulerM',...
                            'ErroRK2', 'ErroRK4','ErroODE45','ErroPM'};


                end
                
               
                T = array2table(tabela);
                app.UITable.Data = T;   
                app.UITable.ColumnName= nomeColunas;
                
                hold (app.UIAxes,"off")
                grid (app.UIAxes,"on")
                legend(app.UIAxes,legenda)
                    
           catch ME
                   errordlg(ME.message,"Erro","modal")
               
               
           end


        end

        % Menu selected function: mAutores
        function mAutoresSelected(app, event)
            img1 = imread('Martim Antunes.jpg');
            subplot(1,2,1); 
            imshow(img1);
            text(50, 50, 'Martim Antunes', 'Color', 'white', 'FontSize', 12);
            img2 = imread('Pedro Faneca.jpg');
            subplot(1,2,2);
            imshow(img2);
            text(50,50,'Pedro Faneca','Color','white','FontSize',12);
            
        end

        % Menu selected function: mRelatorioMenu
        function mRelatorioMenuSelected(app, event)
            folder = fileparts(pwd); 
            filename = fullfile(folder, 'AT03TrabMNEDO_MartimAntunes_PedroFaneca', 'Relatório-Atividade03.pdf');
            open(filename)
        end

        % Menu selected function: mSairMenu
        function mSairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.AM2_AT03UIFigure)
            end
        end

        % Menu selected function: ExportarMenu
        function ExportarMenuSelected(app, event)
           [file, path] = uiputfile('.xlsx', 'Exportar para Excel');
           if isequal(file, 0) || isequal(path, 0)
           return
           end
           colunas=app.UITable.ColumnName;
           
           writetable(app.UITable.Data, fullfile(path, file), 'Sheet', 'Tabela');
           writecell(colunas.',fullfile(path,file));
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           app.eF.Value = '';  
           app.eA.Value = 0;  
           app.eB.Value = 0;   
           app.eN.Value = 0;   
           app.eY0.Value = 0;  
           cla(app.UIAxes);
           app.UITable.Data = [];  
        end

        % Menu selected function: AjudaMenu
        function AjudaMenuSelected(app, event)
            folder = fileparts(pwd); 
            filename = fullfile(folder, 'AT03TrabMNEDO_MartimAntunes_PedroFaneca', 'Ajuda.pdf'); 
            open(filename);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AM2_AT03UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {443, 443};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {293, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AM2_AT03UIFigure = uifigure('Visible', 'off');
            app.AM2_AT03UIFigure.Name = 'AM2_AT03';
            app.AM2_AT03UIFigure.Icon = fullfile(pathToMLAPP, 'logo.jpg');
            app.AM2_AT03UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FicheiroMenu = uimenu(app.AM2_AT03UIFigure);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.ExportarMenu = uimenu(app.FicheiroMenu);
            app.ExportarMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarMenuSelected, true);
            app.ExportarMenu.Text = 'Exportar';
            app.AjudaMenu = uimenu(app.FicheiroMenu);
            app.AjudaMenu.MenuSelectedFcn = createCallbackFcn(app, @AjudaMenuSelected, true);
            app.AjudaMenu.Text = 'Ajuda';
            app.mSairMenu = uimenu(app.FicheiroMenu);
            app.mSairMenu.MenuSelectedFcn = createCallbackFcn(app, @mSairMenuSelected, true);
            app.mSairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.AM2_AT03UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.mRelatorioMenu = uimenu(app.SobreMenu);
            app.mRelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @mRelatorioMenuSelected, true);
            app.mRelatorioMenu.Text = 'Relatório';
            app.mAutores = uimenu(app.SobreMenu);
            app.mAutores.MenuSelectedFcn = createCallbackFcn(app, @mAutoresSelected, true);
            app.mAutores.Text = 'Autores';
            app.GridLayout = uigridlayout(app.AM2_AT03UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.ftyLabel = uilabel(app.DadosPVI);
            app.ftyLabel.Text = 'f(t,y) =';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Value = '-2*t*y';
            app.aLabel = uilabel(app.DadosPVI);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.aLabel_2 = uilabel(app.DadosPVI);
            app.aLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.eB.Value = 1.5;
            app.aLabel_3 = uilabel(app.DadosPVI);
            app.aLabel_3.Text = 'n';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.eN.Value = 10;
            app.aLabel_4 = uilabel(app.DadosPVI);
            app.aLabel_4.Text = 'y0';
            app.eY0 = uieditfield(app.DadosPVI, 'numeric');
            app.eY0.Value = 2;
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos';
            app.rbEuller = uiradiobutton(app.bgMetodos);
            app.rbEuller.Text = 'Euler';
            app.rbEuller.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bgMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbPM = uiradiobutton(app.bgMetodos);
            app.rbPM.Text = 'Ponto Médio';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos ';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Métodos numéricos para PVI')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.AM2_AT03UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AM2_AT03UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AM2_AT03UIFigure)
        end
    end
end