classdef MaquinaMNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AM2_AT04                   matlab.ui.Figure
        FicheiroMenu               matlab.ui.container.Menu
        ExportarMenu               matlab.ui.container.Menu
        AjudaMenu                  matlab.ui.container.Menu
        SairMenu                   matlab.ui.container.Menu
        SobreMenu                  matlab.ui.container.Menu
        AutoresMenu                matlab.ui.container.Menu
        RelatrioMenu               matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ddProblemas                matlab.ui.control.DropDown
        ProblemasPrDefinidosLabel  matlab.ui.control.Label
        bReset                     matlab.ui.control.Button
        bAtualizar                 matlab.ui.control.Button
        bgMetodos                  matlab.ui.container.ButtonGroup
        rbTodos                    matlab.ui.control.RadioButton
        rbRK4                      matlab.ui.control.RadioButton
        rbRK2                      matlab.ui.control.RadioButton
        rbEulerM                   matlab.ui.control.RadioButton
        rbEuler                    matlab.ui.control.RadioButton
        DadosdoPVIPanel            matlab.ui.container.Panel
        eV0                        matlab.ui.control.NumericEditField
        aEditFieldLabel_5          matlab.ui.control.Label
        eG                         matlab.ui.control.EditField
        gtuvLabel                  matlab.ui.control.Label
        eU0                        matlab.ui.control.NumericEditField
        aEditFieldLabel_4          matlab.ui.control.Label
        eN                         matlab.ui.control.NumericEditField
        aEditFieldLabel_3          matlab.ui.control.Label
        eB                         matlab.ui.control.NumericEditField
        aEditFieldLabel_2          matlab.ui.control.Label
        eA                         matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        eF                         matlab.ui.control.EditField
        ftuvLabel                  matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
             cla(app.UIAxes)
            syms u(t) v(t)
            strF = app.eF.Value;
            f = @(t,u,v) eval(vectorize(strF));
            strG = app.eG.Value;
            g = @(t,u,v) eval(vectorize(strG));  

            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            u0 = app.eU0.Value;
            v0 = app.eV0.Value;
            value = app.ddProblemas.Value;
 
            escolhabgMetodos = app.bgMetodos.SelectedObject;
            rbMetodoSelecionado = find([app.rbEuler,...
                                        app.rbEulerM,...
                                        app.rbRK2,...
                                        app.rbRK4,...
                                        app.rbTodos]==escolhabgMetodos);
 
            erroFuncaoF = MException("Matlab:bAtualizar:badF",...
                                    "Introduza uma função em t, u e v" );
            
            erroFuncaoG = MException("Matlab:bAtualizar:badG",...
                                    "Introduza uma função em t, u e v" );
            
            erroA = MException("MATLAB:bAtualizar:badA", ...
                                   "Introduza  um número real");
            
            erroB = MException("Matlab:bAtualizar:badB",...
                                    "Introduza um b > a" );
            
            erroU0V0 = MException("MATLAB:bAtualizar:badU0V0", ...
                                   "Introduza  um número real");
            
            
            erroN = MException("MATLAB:bAtualizar:badA", ...
                                   "Introduza  um número real positivo " + ...
                                   "e maior ou igual a 2") ;
            
            try
                try
                    z = f(t,u,v);
                    app.eF.BackgroundColor = "w";
 
                catch ME
                    app.eF.BackgroundColor = "r";
                    throw(erroFuncaoF)
                end
                
                try
                    c = g(t,u,v);
                    app.eG.BackgroundColor = "w";
                catch ME
                    app.eG.BackgroundColor = "r";
                    throw(erroFuncaoG);
                end
                
                if isscalar(a) && isreal(a)
                    app.eA.BackgroundColor = "w";
                else
                    app.eA.BackgroundColor = "r";
                    throw(erroA);
                end

                if isscalar(b) && isreal(b) && b>a
                     app.eB.BackgroundColor = "w";
                else
                     app.eB.BackgroundColor = "r";
                     throw(erroB);
                end

                if isscalar(n) && isreal(n) && (n>=2)
                      app.eN.BackgroundColor = "w";
                 else
                      app.eN.BackgroundColor = "r";
                      throw(erroN);
                 end
                
                if isscalar(u0) && isreal(u0)
                      app.eU0.BackgroundColor = "w";
                 else
                      app.eU0.BackgroundColor = "r";
                      throw(erroU0V0);
                 end 
                if isscalar(v0) && isreal(v0)
                      app.eV0.BackgroundColor = "w";
                 else
                      app.eV0.BackgroundColor = "r";
                      throw(erroU0V0)
                 end 


                 eqns = [diff(u,t)==v,diff(v,t)==-16*u];
                if(value == "Mola massa sem amortecimento")
                    eqns = [diff(u,t)==v,diff(v,t)==-16*u];
                end
                if(value == "Mola massa com amortecimento")
                    eqns = [diff(u,t)==v,diff(v,t)==-10*v-25*u];
                end
                
                if(value=="Modelo vibratório mecânico")
                    eqns = [diff(u,t)==v,diff(v,t)==-2*v-2*u+4*cos(t)+2*sin(t)];
                end

                if(value=="Circuitos elétricos em série")
                    eqns = [diff(u,t)==v,diff(v,t)==-5*v-4*u+2*exp(-t/4)];
                end


                 ci = [u(0) == u0, v(0) == v0];
                 [sExataU,~] = dsolve(eqns, ci);
                 h = @(t) eval(vectorize(char(sExataU)));


                [~,uEuler,~] = EulerSED(f,g,a,b,n,u0,v0);
                [~,uEulerM,~] = EulerMSED(f,g,a,b,n,u0,v0);
                [~,uRK2,~] = RK2SED(f,g,a,b,n,u0,v0);
                [t,uRK4,~] = RK4SED(f,g,a,b,n,u0,v0);
                uExata = h(t);
                
               erroEuler = abs(uExata-uEuler);
               erroEulerM = abs(uExata-uEulerM);
               erroRK2 = abs(uExata-uRK2);
               erroRK4 = abs (uExata-uRK4);
               
               
                h = (b-a)/n;
                t = a:h:b;
     
                
                switch rbMetodoSelecionado
                    case 1
                        if (value=="Pêndulo")
                            plot(app.UIAxes,t,uEuler,"-og")
                            matrizResultados = [t.',uEuler.'];
                            legendaGrafico = ["Euler"];
                            NomeColunasTabela = ["t","Euler"];
                        else
                            plot(app.UIAxes,t,uExata,"-*r");
                            hold(app.UIAxes,"on")  
                            plot(app.UIAxes,t,uEuler,"-og");
                            matrizResultados = [t.', uExata.', uEuler.',erroEuler.']; 
                            legendaGrafico = ["Exata","Euler"];
                            NomeColunasTabela = ["t","Exata","Euler","ErroEuler"];
                        end
                    case 2
                        if (value=="Pêndulo")
                            plot(app.UIAxes,t,uEulerM,"-om")
                            matrizResultados = [t.',uEulerM.'];
                            legendaGrafico = ["EulerM"];
                            NomeColunasTabela = ["t","EulerM"];
                        else
                            plot(app.UIAxes,t,uExata,"-*r");
                            hold(app.UIAxes,"on")  
                            plot(app.UIAxes,t,uEulerM,"-om")
                            matrizResultados = [t.', uExata.', uEulerM.',erroEulerM.']; 
                            legendaGrafico = ["Exata","EulerM"];
                            NomeColunasTabela = ["t","Exata","EulerM","ErroEulerM"];
                        end
                    case 3
                         if (value=="Pêndulo")
                            plot(app.UIAxes,t,uRK2,"-ob")
                            matrizResultados = [t.',uRK2.'];
                            legendaGrafico = ["RK2"];
                            NomeColunasTabela = ["t","RK2"];
                         else
                            plot(app.UIAxes,t,uExata,"-*r");
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,uRK2,"-ob")
                            matrizResultados = [t.', uExata.', uRK2.',erroRK2.']; 
                            legendaGrafico = ["Exata","RK2"];
                            NomeColunasTabela = ["t","Exata","RK2","ErroRK2"];
                         end
                    case 4
                        if (value=="Pêndulo")
                            plot(app.UIAxes,t,uRK4,"-ok")
                            matrizResultados = [t.',uRK4.'];
                            legendaGrafico = ["RK4"];
                            NomeColunasTabela = ["t","RK4"];
                        else
                            plot(app.UIAxes,t,uExata,"-*r");
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,uRK4,"-ok")
                            matrizResultados = [t.', uExata.', uRK4.',erroRK4.']; 
                            legendaGrafico = ["Exata","RK4"];
                            NomeColunasTabela = ["t","Exata","RK4","ErroRK4"];
                        end
                    case 5
                         if (value=="Pêndulo")
                             plot(app.UIAxes, t, uEuler, "-og", t, uEulerM, "-om", ...
                             t, uRK2, "-ob", t, uRK4, "-ok");
                             matrizResultados = [t.', uEuler.',uEulerM.',...
                             uRK2.',uRK4.']; 
                             legendaGrafico = ["Euler","EulerM","RK2","RK4"];
                             NomeColunasTabela = ["t","Euler","EulerM",... 
                            "RK2","RK4"];
                         else
                            plot(app.UIAxes,t,uExata,"-*r");
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,t,uEuler,"-og")
                            plot(app.UIAxes,t,uEulerM,"-om")
                            plot(app.UIAxes,t,uRK2,"-ob")
                            plot(app.UIAxes,t,uRK4,"-ok")
                            matrizResultados = [t.', uExata.', uEuler.',uEulerM.',...
                            uRK2.',uRK4.',erroEuler.',erroEulerM.',erroRK2.',...
                            erroRK4.']; 
                            legendaGrafico = ["Exata","Euler","EulerM","RK2","RK4"];
                            NomeColunasTabela = ["t","Exata","Euler","EulerM",... 
                            "RK2","RK4","ErroEuler","ErroEulerM","ErroRK2",...
                            "ErroRK4"];  
                         end
 
                end


                app.UITable.Data = array2table(matrizResultados);
                app.UITable.ColumnName = NomeColunasTabela;
                hold(app.UIAxes,"off")
                grid(app.UIAxes,"on")     
                legend(app.UIAxes,legendaGrafico)


            catch ME
                errordlg(ME.message,"ERRO","modal")
            end

        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.AM2_AT04)
            end
        end

        % Value changed function: ddProblemas
        function ddProblemasValueChanged(app, event)
    
          value=app.ddProblemas.Value;
            
            switch value
                case "Mola massa sem amortecimento"
                    strG = "-16*u";
                    a = 0; b = 4; n = 100;
                    u0 = 9; v0 = 0;
                case "Mola massa com amortecimento"
                    strG = "-10*v-25*u";
                    a = 0; b = 4; n = 100;
                    u0 = 0; v0 = -4;
                    

                case "Pêndulo"
                    strG = "-sin(u)-0.3*v"; 
                    a = 0; b = 4; n = 100;
                    u0 = pi/2; v0 = 0;
                    
                    
                case "Modelo vibratório mecânico"
                    strG = "-2*v-2*u+4*cos(t)+2*sin(t)"; 
                    a = 0; b = 4; n = 100;
                    u0 = 0; v0 = 3;
                   
                case "Circuitos elétricos em série"
                    strG = "-5*v-4*u+2*exp(-t/4)"; 
                    a = 0; b = 4; n = 100;
                    u0 = 0; v0 = 0;
            
                    
            end


            app.eG.Value = strG;
            app.eA.Value = a;
            app.eB.Value = b;
            app.eN.Value = n;
            app.eU0.Value = u0;
            app.eV0.Value = v0;


  bAtualizarButtonPushed(app, [])
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            img1 = imread('Martim Antunes.jpg');
            subplot(1,2,1); 
            imshow(img1);
            text(50, 50, 'Martim Antunes', 'Color', 'white', 'FontSize', 12);
            im2 = imread('Pedro Faneca.jpg');
            subplot(1,2,2);
            imshow(im2);
            text(50,50,'Pedro Faneca','Color','white','FontSize',12);
        end

        % Button pushed function: bReset
        function bResetButtonPushed(app, event)
           app.eG.Value = '';
           app.eA.Value = 0;  
           app.eB.Value = 0;   
           app.eN.Value = 0;   
           app.eU0.Value = 0;  
           app.eV0.Value = 0;
           cla(app.UIAxes);
           app.UITable.Data = [];
           
        end

        % Menu selected function: AjudaMenu
        function AjudaMenuSelected(app, event)
            folder = fileparts(pwd); 
            filename = fullfile(folder, 'At04Trab_MNSED_MartimAntunes_PedroFaneca', 'Ajuda.pdf'); 
            open(filename);
        end

        % Menu selected function: ExportarMenu
        function ExportarMenuSelected(app, event)
             [file, path] = uiputfile('.xlsx', 'Exportar para Excel');
           if isequal(file, 0) || isequal(path, 0)
           return
           end
           colunas=app.UITable.ColumnName;
           writetable(app.UITable.Data, fullfile(path, file), 'Sheet', 'Tabela');
           writecell(colunas.',fullfile(path,file));
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            folder = fileparts(pwd); 
            filename = fullfile(folder, 'At04Trab_MNSED_MartimAntunes_PedroFaneca', 'Relatório-Atividade04.pdf');
            open(filename)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AM2_AT04.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {430, 430};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AM2_AT04 = uifigure('Visible', 'off');
            app.AM2_AT04.Name = 'AM2_AT04';
            app.AM2_AT04.Icon = fullfile(pathToMLAPP, 'logo.jpg');
            app.AM2_AT04.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FicheiroMenu = uimenu(app.AM2_AT04);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.ExportarMenu = uimenu(app.FicheiroMenu);
            app.ExportarMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarMenuSelected, true);
            app.ExportarMenu.Text = 'Exportar';
            app.AjudaMenu = uimenu(app.FicheiroMenu);
            app.AjudaMenu.MenuSelectedFcn = createCallbackFcn(app, @AjudaMenuSelected, true);
            app.AjudaMenu.Text = 'Ajuda';
            app.SairMenu = uimenu(app.FicheiroMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.AM2_AT04);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.RelatrioMenu = uimenu(app.SobreMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.GridLayout = uigridlayout(app.AM2_AT04);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 4;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'n';
            app.eN = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eN.Value = 100;
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'u0 =';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 9;
            app.gtuvLabel = uilabel(app.DadosdoPVIPanel);
            app.gtuvLabel.Text = 'g(t,u,v) = ';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = '-16*u';
            app.aEditFieldLabel_5 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_5.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos Numéricos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.bReset = uibutton(app.LeftPanel, 'push');
            app.bReset.ButtonPushedFcn = createCallbackFcn(app, @bResetButtonPushed, true);
            app.bReset.Text = 'Reset';
            app.ProblemasPrDefinidosLabel = uilabel(app.LeftPanel);
            app.ProblemasPrDefinidosLabel.Text = 'Problemas Pré-Definidos';
            app.ddProblemas = uidropdown(app.LeftPanel);
            app.ddProblemas.Items = {'Mola massa sem amortecimento', 'Mola massa com amortecimento', 'Pêndulo', 'Modelo vibratório mecânico', 'Circuitos elétricos em série'};
            app.ddProblemas.ValueChangedFcn = createCallbackFcn(app, @ddProblemasValueChanged, true);
            app.ddProblemas.Value = 'Mola massa sem amortecimento';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Métodos Numéricos para PVI/SED')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'u')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.AM2_AT04.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaMNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AM2_AT04)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AM2_AT04)
        end
    end
end