classdef MaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AM2_AT05                    matlab.ui.Figure
        FicheiroMenu                matlab.ui.container.Menu
        ExportarMenu                matlab.ui.container.Menu
        AjudaMenu                   matlab.ui.container.Menu
        SairMenu                    matlab.ui.container.Menu
        SobreMenu                   matlab.ui.container.Menu
        AutoresMenu                 matlab.ui.container.Menu
        RelatrioMenu                matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        FunorealdevarivelrealyfxDerivaoTab  matlab.ui.container.Tab
        ResetButton                 matlab.ui.control.Button
        ey                          matlab.ui.control.EditField
        yEditFieldLabel             matlab.ui.control.Label
        DerivaoNumricaLabel         matlab.ui.control.Label
        UITable_derivacao           matlab.ui.control.Table
        atualizar_derivacao         matlab.ui.control.Button
        bgDiferencas                matlab.ui.container.ButtonGroup
        rbTodas                     matlab.ui.control.RadioButton
        rb2Derivada_3               matlab.ui.control.RadioButton
        rb_Centradas_3              matlab.ui.control.RadioButton
        rbRegressivas_3             matlab.ui.control.RadioButton
        rbProgressivas_3            matlab.ui.control.RadioButton
        rbRegressivas_2             matlab.ui.control.RadioButton
        rbProgressivas_2            matlab.ui.control.RadioButton
        DadosdafunoPanel_2          matlab.ui.container.Panel
        eFx                         matlab.ui.control.EditField
        ftyLabel                    matlab.ui.control.Label
        eb                          matlab.ui.control.NumericEditField
        bLabel_2                    matlab.ui.control.Label
        eh                          matlab.ui.control.NumericEditField
        hLabel                      matlab.ui.control.Label
        ea                          matlab.ui.control.NumericEditField
        aLabel                      matlab.ui.control.Label
        UIAxes_derivacao            matlab.ui.control.UIAxes
        UIDeriva                    matlab.ui.control.UIAxes
        FunorealdevarivelrealyfxIntegraoTab  matlab.ui.container.Tab
        ResetButton_2               matlab.ui.control.Button
        bgGrafico                   matlab.ui.container.ButtonGroup
        EixosCheckBox               matlab.ui.control.CheckBox
        GrelhaCheckBox              matlab.ui.control.CheckBox
        checkarea                   matlab.ui.control.CheckBox
        UITable_integracao          matlab.ui.control.Table
        Atualizar_integracao        matlab.ui.control.Button
        bgIntegracao                matlab.ui.container.ButtonGroup
        rbTodos                     matlab.ui.control.RadioButton
        rbRegraSimpson              matlab.ui.control.RadioButton
        rbRegraTrapezios            matlab.ui.control.RadioButton
        DadosdafunoPanel_3          matlab.ui.container.Panel
        eFx_2                       matlab.ui.control.EditField
        ftyLabel_2                  matlab.ui.control.Label
        eb_2                        matlab.ui.control.NumericEditField
        bLabel_3                    matlab.ui.control.Label
        en                          matlab.ui.control.NumericEditField
        hLabel_2                    matlab.ui.control.Label
        ea_2                        matlab.ui.control.NumericEditField
        aLabel_2                    matlab.ui.control.Label
        IntegraoNumricaLabel        matlab.ui.control.Label
        UIAxes_integracao           matlab.ui.control.UIAxes
        UIIntegracao                matlab.ui.control.UIAxes
        Funorealde2variveiszfxyTab  matlab.ui.container.Tab
        ResetButton_3               matlab.ui.control.Button
        Panel_Parciais              matlab.ui.container.Panel
        ddDerivadasParciais         matlab.ui.control.DropDown
        UIMostrador_2               matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        LabelHarmonica              matlab.ui.control.Label
        UIMostrador                 matlab.ui.control.UIAxes
        bAtualizar                  matlab.ui.control.Button
        DadosdafunoPanel            matlab.ui.container.Panel
        eC                          matlab.ui.control.NumericEditField
        aEditFieldLabel_2           matlab.ui.control.Label
        eH2                         matlab.ui.control.NumericEditField
        eD                          matlab.ui.control.NumericEditField
        dLabel                      matlab.ui.control.Label
        h2Label                     matlab.ui.control.Label
        bLabel                      matlab.ui.control.Label
        eB                          matlab.ui.control.NumericEditField
        eH1                         matlab.ui.control.NumericEditField
        h1Label                     matlab.ui.control.Label
        eA                          matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        eFxy                        matlab.ui.control.EditField
        fxyEditFieldLabel           matlab.ui.control.Label
        UIAxes_curvas               matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function YN = isHarmonica(~,f)
            syms z(x,y)
            if diff(f,x,2)+diff(diff(f,y),y)==0
                YN = 1;
            else 
                YN = 0;
            end           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
            atualizar_derivacaoPushed(app, [])
            ddDerivadasParciaisValueChanged(app, [])
            Atualizar_integracaoPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms z(x,y)
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; h1 = app.eH1.Value; b = app.eB.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;
            
            erroFuncao = MException("Matlab:bAtualizar:BadF",...
                                    " Introduza uma função em x e y");
            
            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch ME
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFuncao)  
                end
                [x,y] = meshgrid(a:h1:b, c:h2:d);
                z = f(x,y);
                surfc(app.UIAxes,x,y,z)
                contour(app.UIAxes_curvas,x,y,z)
                cla(app.UIMostrador)
                eqLaplace = ['$',...
                '\frac{\partial^2 f}{\partial x^2}+ \frac{\partial^2 f}{\partial y^2}=0',...
                '$'];
                text(app.UIMostrador,0.1,0.5,eqLaplace,...
                    "Interpreter","latex","FontSize",18)                    
                
               % YN = isHarmonica(app,f);
                if isHarmonica(app,f)
                    textoHarmonica = "A função é harmónica";
                    corHarmonica = "g";
                else
                    textoHarmonica = "A função não é harmónica";
                    corHarmonica = "r";                    
                end
                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontColor = corHarmonica;


            catch ME
                errordlg(ME.message,"ERRO","modal") 
            end         
            
        end

        % Button pushed function: atualizar_derivacao
        function atualizar_derivacaoPushed(app, event)
            cla(app.UIAxes_derivacao)

            syms x
            strF = app.eFx.Value;
            f = @(x) eval(vectorize(strF));
            
            df = diff(f,x);
            ddf = diff(df,x);
            a = app.ea.Value;
            h = app.eh.Value;
            b = app.eb.Value;
            
            y =app.ey.Value;

            escolhabgDiferencas = app.bgDiferencas.SelectedObject;
            rbDiferencaSelecionada = find([app.rbProgressivas_2,...
                                        app.rbRegressivas_2,...
                                        app.rbProgressivas_3,...
                                        app.rbRegressivas_3,...
                                        app.rb_Centradas_3,...
                                        app.rb2Derivada_3,...
                                        app.rbTodas]==escolhabgDiferencas);


            erroFuncao = MException("Matlab:bAtualizar:badF",...
                                    " Introduza uma função em x");
            
            
            erroA = MException("MATLAB:bAtualizar:badA", ...
                                   "Introduza  um número real");
            
            erroB = MException("Matlab:bAtualizar:badB",...
                                    "Introduza um b > a" );


            erroH = MException("Matlab:hAtualizar:badH",...
                                      "Introduza um numero h > 0");
            try
                try
                    y = f(x);
                    app.eFx.BackgroundColor = "w";
                catch ME
                    app.eFx.BackgroundColor = "r";
                    throw(erroFuncao)  
                end


                if isscalar(a) && isreal(a)
                    app.ea.BackgroundColor = "w";
                else
                    app.ea.BackgroundColor = "r";
                    throw(erroA);
                end

                if isscalar(b) && isreal(b) && b>a
                     app.eb.BackgroundColor = "w";
                else
                     app.eb.BackgroundColor = "r";
                     throw(erroB);
                end

                if isscalar(h) && isreal(h) && (h>0)
                      app.eh.BackgroundColor = "w";
                else
                      app.eh.BackgroundColor = "r";
                      throw(erroH);
                end


            [x,y,dydxDFP] = DFProgressivas_2(f,a,b,h);
            [x,y,dydxDFR] = DFRegressivas_2(f,a,b,h);
            [x,y,dydxDFP3] = DFProgressivas_3(f,a,b,h);
            [x,y,dydxDFR3] = DFRegressivas_3(f,a,b,h);
            [x,y,dydxDFC] = DFCentradas_3(f,a,b,h);
            [x,y,dydxD2] = DFDerivada2_3(f,a,b,h);
            
            
            derivada2=0 ;
            if derivada2==1
                dydxExata = eval(ddf);
            else
                dydxExata = eval(df);
            end
            
             erroDFP = abs(dydxExata-dydxDFP);
             erroDFR = abs(dydxExata-dydxDFR);
             erroDFP3 = abs(dydxExata-dydxDFP3);
             erroDFR3 = abs(dydxExata-dydxDFR3);
             erroDFC = abs(dydxExata-dydxDFC);
             erroD2 = abs(dydxExata-dydxD2);
            
            g =@(x) eval(vectorize(char(dydxExata)));


            switch rbDiferencaSelecionada
                case 1
                    plot(app.UIAxes_derivacao,x,y,"*-k")
                    hold(app.UIAxes_derivacao,"on")
                    plot(app.UIAxes_derivacao,x,dydxExata,"*-r")
                    plot(app.UIAxes_derivacao,x,dydxDFP,"-og")
                    hold(app.UIAxes_derivacao,"off");
                    matrizResultados = [x.',y.',dydxExata.',dydxDFP.',erroDFP.'];
                    legendaGrafico = ["Função","Exata","DFP"];
                    NomeColunasTabela = ["x","y","Exata","DFP","erroDFP"];

                case 2
                    plot(app.UIAxes_derivacao,x,y,"*-k")
                    hold(app.UIAxes_derivacao,"on")
                    plot(app.UIAxes_derivacao,x,dydxExata,"*-r")
                    plot(app.UIAxes_derivacao,x,dydxDFR,"-ob");
                    hold(app.UIAxes_derivacao,"off");
                    matrizResultados = [x.',y.',dydxExata.',dydxDFR.',erroDFR.'];
                    legendaGrafico = ["Função","Exata","DFR"];
                    NomeColunasTabela = ["x","y","Exata","DFR","erroDFR"];

                case 3
                    plot(app.UIAxes_derivacao,x,y,"*-k")
                    hold(app.UIAxes_derivacao,"on")
                    plot(app.UIAxes_derivacao,x,dydxExata,"*-r")
                    plot(app.UIAxes_derivacao,x,dydxDFP3,"-om");
                    hold(app.UIAxes_derivacao,"off");
                    matrizResultados = [x.',y.',dydxExata.',dydxDFP3.',erroDFP3.'];
                    legendaGrafico = ["Função","Exata","DFP3"];
                    NomeColunasTabela = ["x","y","Exata","DFP3","erroDFP3"];

                case 4
                    plot(app.UIAxes_derivacao,x,y,"*-k")
                    hold(app.UIAxes_derivacao,"on")
                    plot(app.UIAxes_derivacao,x,dydxExata,"*-r")
                    plot(app.UIAxes_derivacao,x,dydxDFR3,"-oy");
                    hold(app.UIAxes_derivacao,"off");
                    matrizResultados = [x.',y.',dydxExata.',dydxDFR3.',erroDFR3.'];
                    legendaGrafico = ["Função","Exata","DFR3"];
                    NomeColunasTabela = ["x","y","Exata","DFR3","erroDFR3"];
                case 5
                    plot(app.UIAxes_derivacao,x,y,"*-k")
                    hold(app.UIAxes_derivacao,"on")
                    plot(app.UIAxes_derivacao,x,dydxExata,"*-r")
                    plot(app.UIAxes_derivacao,x,dydxDFC,"-oc");
                    hold(app.UIAxes_derivacao,"off");
                    matrizResultados = [x.',y.',dydxExata.',dydxDFC.',erroDFC.'];
                    legendaGrafico = ["Função","Exata","DFC"];
                    NomeColunasTabela = ["x","y","Exata","DFC","erroDFC"];

                case 6
                    derivada2=1;
                    if derivada2==1
                        dydxExata = eval(ddf);
                    else
                        dydxExata = eval(df);
                    end
                    erroD2 = abs(dydxExata-dydxD2);
                    plot(app.UIAxes_derivacao,x,y,"*-k")
                    hold(app.UIAxes_derivacao,"on")
                    plot(app.UIAxes_derivacao,x,dydxExata,"*-r")
                    plot(app.UIAxes_derivacao,x,dydxD2,"-om");
                    hold(app.UIAxes_derivacao,"off");
                    matrizResultados = [x.',y.',dydxExata.',dydxD2.',erroD2.'];
                    legendaGrafico = ["Função","Exata","DFD2"];
                    NomeColunasTabela = ["x","y","Exata","DFD2","erroDFD2"];

                case 7
                    plot(app.UIAxes_derivacao,x,y,"*-k")
                    hold(app.UIAxes_derivacao,"on")
                    plot(app.UIAxes_derivacao,x,dydxExata,"*-r")
                    plot(app.UIAxes_derivacao,x,dydxDFP,"-og")
                    plot(app.UIAxes_derivacao,x,dydxDFR,"-ob");
                    plot(app.UIAxes_derivacao,x,dydxDFP3,"-om");
                    plot(app.UIAxes_derivacao,x,dydxDFR3,"-oy");
                    plot(app.UIAxes_derivacao,x,dydxDFC,"-oc");
                    plot(app.UIAxes_derivacao,x,dydxD2,"-om");
                    hold(app.UIAxes_derivacao,"off");
                    matrizResultados = [x.',y',dydxExata.',dydxDFP.',dydxDFR.',...
                        dydxDFP3.',dydxDFR3.',dydxDFC.',dydxD2.',erroDFP.',erroDFR.',...
                        erroDFP3.',erroDFR3.',erroDFC.',erroD2.'];
                    legendaGrafico = ["Função","Exata","DFP","DFR","DFP3",...
                        "DFR3","DFC","DFD2"];
                    NomeColunasTabela = ["x","y","Exata","DFP","DPR","DFP3",...
                        "DFR3","DFC","DFD2","ErroDFP","ErroDFR","ErroDFP3",...
                        "ErroDFR3","ErroDFC","ErroDFD2"];
            
            end
            
            grid(app.UIAxes_derivacao,"on")
            legend(app.UIAxes_derivacao,legendaGrafico)
 
            app.UITable_derivacao.Data = array2table(matrizResultados);
            app.UITable_derivacao.ColumnName = NomeColunasTabela;


            formaLatex =['$','\frac{dy}{dx}=',latex(df),'$'];
            cla(app.UIDeriva)
            text(app.UIDeriva,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)     


            catch ME
                errordlg(ME.message,"ERRO","modal") 
            end


        end

        % Value changed function: ddDerivadasParciais
        function ddDerivadasParciaisValueChanged(app, event)
            syms z(x,y)
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            value = app.ddDerivadasParciais.Value;
             switch(value)
            
                 case "Ordem a x"
                    dzdx = diff(f,x);
                    dzdx_latex = ['$' '\frac{\partial f}{\partial x} = ' latex(dzdx) '$'];
                    cla(app.UIMostrador_2)
                    text(app.UIMostrador_2,0.1,0.5,dzdx_latex,'units','normalized','Interpreter','latex','FontSize',20)
            
                 case "Ordem a y"
                    dzdy = diff(f,y);
                    dzdy_latex = ['$' '\frac{\partial f}{\partial y} = ' latex(dzdy) '$'];
                    cla(app.UIMostrador_2)
                    text(app.UIMostrador_2,0.1,0.5,dzdy_latex,'units','normalized','Interpreter','latex','FontSize',20)
            end
        end

        % Button pushed function: Atualizar_integracao
        function Atualizar_integracaoPushed(app, event)
            cla(app.UIAxes_integracao)

            syms x
            strF = app.eFx_2.Value;
            f = @(x) eval(vectorize(strF));
            a = app.ea_2.Value;
            b = app.eb_2.Value;
            n = app.en.Value;

            erroFuncao = MException("Matlab:bAtualizar:badF1",...
                                    " Introduza uma função em x");
            
            
            erroA = MException("MATLAB:bAtualizar:badA1", ...
                                   "Introduza  um número real");
            
            erroB = MException("Matlab:bAtualizar:badB",...
                                    "Introduza um b > a" );


            erroN = MException("MATLAB:bAtualizar:badN1", ...
                                   "Introduza  um número real positivo " + ...
                                   "e maior ou igual a 2") ;

            try
                try
                    y = f(x);
                    app.eFx_2.BackgroundColor = "w";
                catch ME
                    app.eFx_.BackgroundColor = "r";
                    throw(erroFuncao)  
                end


                if isscalar(a) && isreal(a)
                    app.ea_2.BackgroundColor = "w";
                else
                    app.ea_2.BackgroundColor = "r";
                    throw(erroA);
                end

                if isscalar(b) && isreal(b) && b>a
                     app.eb_2.BackgroundColor = "w";
                else
                     app.eb_2.BackgroundColor = "r";
                     throw(erroB);
                end

                 if isscalar(n) && isreal(n) && (n>=2)
                      app.en.BackgroundColor = "w";
                 else
                      app.en.BackgroundColor = "r";
                      throw(erroN);
                 end


                escolhabgIntegracao = app.bgIntegracao.SelectedObject;
                rbIntegracaoSelecionada = find([app.rbRegraTrapezios,...
                                                app.rbRegraSimpson,...
                                                app.rbTodos]==escolhabgIntegracao);


                T = RTrapezios(f,a,b,n);
                out_S = RSimpson (f,a,b,n);

               exata = double(int(f(sym('x')),a,b));
               g = @(x) eval(vectorize(char(exata)));
 
                
                erroRegraTrapezios = abs(exata-T);
                erroRegraSimpson =  abs(exata-out_S);

                  
                h = (b-a)/n;
                t=a:h:b;
                y=f(t);
    
                    
                plot(app.UIAxes_integracao,t,y);

                switch(rbIntegracaoSelecionada)
                
                    case 1
                        
                        matrizResultados = [exata.',T.',erroRegraTrapezios.'];
                        legendaGrafico = ["Funcao"];
                        NomeColunasTabela=["Exata","R.Trapezios","ErroR.Trapezios"];

                    case 2

                        matrizResultados = [exata.',out_S.',erroRegraSimpson.'];
                        legendaGrafico=["Funcao"];
                        NomeColunasTabela=["Exata","R.Simpson","ErroR.Simpson"];

                    case 3

                        matrizResultados=[exata.',T.',out_S.',erroRegraTrapezios.',erroRegraSimpson.'];
                        legendaGrafico=["Funcao"];
                        NomeColunasTabela=["Exata","R.Trapezios", ...
                            "R.Simpson","ErroR.Trapezios","ErroR.Simpson"];

 
                 end
                hold(app.UIAxes_integracao,"off")
                grid(app.UIAxes_integracao,"on")  
            
              
                legend(app.UIAxes_integracao,legendaGrafico)
 
                app.UITable_integracao.Data = array2table(matrizResultados);
                app.UITable_integracao.ColumnName = NomeColunasTabela;


            F = int(f,x);
    
            F_latex = ['$' '\int f(x)\,dx = ' latex(F) '+c' '$'];
           
            cla(app.UIIntegracao)
            text(app.UIIntegracao,0.1,0.5,F_latex,"Interpreter","latex","FontSize",18)     
            

            catch ME
                errordlg(ME.message,"ERRO","modal") 
            end

        end

        % Value changed function: checkarea
        function checkareaValueChanged(app, event)
           
            strF = app.eFx_2.Value;
            f = @(x) eval(vectorize(strF));
            a = app.ea_2.Value;
            b = app.eb_2.Value;
            n = app.en.Value;
            h = (b-a)/n;
            t=a:h:b;
            y=f(t);
            
            value = app.checkarea.Value;
            switch value
                case 0
                    plot(app.UIAxes_integracao,t,y);
                    legend(app.UIAxes_integracao, 'Funcao');
                case 1
                    area(app.UIAxes_integracao, t, y, 'FaceColor', 'red');
                    legend(app.UIAxes_integracao, 'Área');
            end


        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            switch(value)
                case 0
                    grid (app.UIAxes_integracao,"off")
                case 1
                    grid (app.UIAxes_integracao,"on")
            end
        end

        % Value changed function: EixosCheckBox
        function EixosCheckBoxValueChanged(app, event)
            value = app.EixosCheckBox.Value;
            
            switch(value)
                case 0
                    xlabel(app.UIAxes_integracao,'')
                    ylabel(app.UIAxes_integracao,'')
                case 1
                    xlabel(app.UIAxes_integracao,'X')
                    ylabel(app.UIAxes_integracao,'Y')
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.AM2_AT05)
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            img1 = imread('Martim Antunes.jpg');
            subplot(1,2,1); 
            imshow(img1);
            text(50, 50, 'Martim Antunes', 'Color', 'white', 'FontSize', 12);
            im2 = imread('Pedro Faneca.jpg');
            subplot(1,2,2);
            imshow(im2);
            text(50,50,'Pedro Faneca','Color','white','FontSize',12);
        end

        % Menu selected function: ExportarMenu
        function ExportarMenuSelected(app, event)
            [file, path] = uiputfile('.xlsx', 'Exportar para Excel');
           if isequal(file, 0) || isequal(path, 0)
           return
           end
           colunas=app.UITable_derivacao.ColumnName;
           writetable(app.UITable_derivacao.Data, fullfile(path, file), 'Sheet', 'Tabela');
           writecell(colunas.',fullfile(path,file));
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.eFx.Value='';
            app.ea.Value=0;
            app.eb.Value=0;
            app.eh.Value=0;
            cla(app.UIDeriva)
            cla(app.UIAxes_derivacao)
            app.UITable_derivacao.Data=[];

        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.eFx_2.Value='';
            app.ea_2.Value=0;
            app.eb_2.Value=0;
            app.en.Value=0;
            cla(app.UIIntegracao)
            cla(app.UIAxes_integracao)
            app.UITable_integracao.Data=[];
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.eFxy.Value='';
            app.eA.Value=0;
            app.eB.Value=0;
            app.eH1.Value=0;
            app.eC.Value=0;
            app.eD.Value=0;
            app.eH2.Value=0;
            cla(app.UIMostrador)
            app.LabelHarmonica.Text='';
            cla(app.UIMostrador_2)
            cla(app.UIAxes);
            cla(app.UIAxes_curvas);

        end

        % Menu selected function: AjudaMenu
        function AjudaMenuSelected(app, event)
            folder = fileparts(pwd); 
            filename = fullfile(folder, 'At05TrabMaquinaCDI_MartimAntunes_PedroFaneca', 'Ajuda.pdf'); 
            open(filename);
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            folder = fileparts(pwd); 
            filename = fullfile(folder, 'At05TrabMaquinaCDI_MartimAntunes_PedroFaneca', 'Relatório-Atividade05.pdf');
            open(filename)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AM2_AT05 = uifigure('Visible', 'off');
            app.AM2_AT05.Name = 'AM2_AT05';
            app.AM2_AT05.Icon = fullfile(pathToMLAPP, 'logo.jpg');
            app.FicheiroMenu = uimenu(app.AM2_AT05);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.ExportarMenu = uimenu(app.FicheiroMenu);
            app.ExportarMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarMenuSelected, true);
            app.ExportarMenu.Text = 'Exportar';
            app.AjudaMenu = uimenu(app.FicheiroMenu);
            app.AjudaMenu.MenuSelectedFcn = createCallbackFcn(app, @AjudaMenuSelected, true);
            app.AjudaMenu.Text = 'Ajuda';
            app.SairMenu = uimenu(app.FicheiroMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.AM2_AT05);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.RelatrioMenu = uimenu(app.SobreMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.TabGroup = uitabgroup(app.AM2_AT05);
            app.FunorealdevarivelrealyfxDerivaoTab = uitab(app.TabGroup);
            app.FunorealdevarivelrealyfxDerivaoTab.Title = 'Função real de variável real y = f(x)- Derivação';
            app.UIDeriva = uiaxes(app.FunorealdevarivelrealyfxDerivaoTab);
            app.UIDeriva.XTick = [];
            app.UIDeriva.YTick = [];
            app.UIAxes_derivacao = uiaxes(app.FunorealdevarivelrealyfxDerivaoTab);
            title(app.UIAxes_derivacao, 'Diferenças Finitas')
            xlabel(app.UIAxes_derivacao, 'X')
            ylabel(app.UIAxes_derivacao, 'Y')
            zlabel(app.UIAxes_derivacao, 'Z')
            app.DadosdafunoPanel_2 = uipanel(app.FunorealdevarivelrealyfxDerivaoTab);
            app.DadosdafunoPanel_2.Title = 'Dados da função';
            app.aLabel = uilabel(app.DadosdafunoPanel_2);
            app.aLabel.Text = 'a';
            app.ea = uieditfield(app.DadosdafunoPanel_2, 'numeric');
            app.hLabel = uilabel(app.DadosdafunoPanel_2);
            app.hLabel.Text = 'h';
            app.eh = uieditfield(app.DadosdafunoPanel_2, 'numeric');
            app.eh.Value = 0.1;
            app.bLabel_2 = uilabel(app.DadosdafunoPanel_2);
            app.bLabel_2.Text = 'b';
            app.eb = uieditfield(app.DadosdafunoPanel_2, 'numeric');
            app.eb.Value = 6.283;
            app.ftyLabel = uilabel(app.DadosdafunoPanel_2);
            app.ftyLabel.Text = 'f(x) =';
            app.eFx = uieditfield(app.DadosdafunoPanel_2, 'text');
            app.eFx.Value = 'sin(x)';
            app.bgDiferencas = uibuttongroup(app.FunorealdevarivelrealyfxDerivaoTab);
            app.bgDiferencas.Title = 'Fórmulas de Diferenças Finitas';
            app.rbProgressivas_2 = uiradiobutton(app.bgDiferencas);
            app.rbProgressivas_2.Text = 'Progressivas (2pontos)';
            app.rbProgressivas_2.Value = true;
            app.rbRegressivas_2 = uiradiobutton(app.bgDiferencas);
            app.rbRegressivas_2.Text = 'Regressivas (2 pontos)';
            app.rbProgressivas_3 = uiradiobutton(app.bgDiferencas);
            app.rbProgressivas_3.Text = 'Progressivas (3pontos)';
            app.rbRegressivas_3 = uiradiobutton(app.bgDiferencas);
            app.rbRegressivas_3.Text = 'Regressivas (3pontos)';
            app.rb_Centradas_3 = uiradiobutton(app.bgDiferencas);
            app.rb_Centradas_3.Text = 'Centradas (3pontos)';
            app.rb2Derivada_3 = uiradiobutton(app.bgDiferencas);
            app.rb2Derivada_3.Text = '2ªDerivada (3 pontos)';
            app.rbTodas = uiradiobutton(app.bgDiferencas);
            app.rbTodas.Text = 'Todas';
            app.atualizar_derivacao = uibutton(app.FunorealdevarivelrealyfxDerivaoTab, 'push');
            app.atualizar_derivacao.ButtonPushedFcn = createCallbackFcn(app, @atualizar_derivacaoPushed, true);
            app.atualizar_derivacao.Text = 'Atualizar';
            app.UITable_derivacao = uitable(app.FunorealdevarivelrealyfxDerivaoTab);
            app.UITable_derivacao.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_derivacao.RowName = {};
            app.DerivaoNumricaLabel = uilabel(app.FunorealdevarivelrealyfxDerivaoTab);
            app.DerivaoNumricaLabel.Text = 'Derivação Numérica';
            app.yEditFieldLabel = uilabel(app.FunorealdevarivelrealyfxDerivaoTab);
            app.yEditFieldLabel.Text = 'y=';
            app.ey = uieditfield(app.FunorealdevarivelrealyfxDerivaoTab, 'text');
            app.ResetButton = uibutton(app.FunorealdevarivelrealyfxDerivaoTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FunorealdevarivelrealyfxIntegraoTab = uitab(app.TabGroup);
            app.FunorealdevarivelrealyfxIntegraoTab.Title = 'Função real de variável real y = f(x)-Integração';
            app.UIIntegracao = uiaxes(app.FunorealdevarivelrealyfxIntegraoTab);
            app.UIIntegracao.XTick = [];
            app.UIIntegracao.YTick = [];
            app.UIAxes_integracao = uiaxes(app.FunorealdevarivelrealyfxIntegraoTab);
            title(app.UIAxes_integracao, 'Métodos de Integração')
            zlabel(app.UIAxes_integracao, 'Z')
            app.IntegraoNumricaLabel = uilabel(app.FunorealdevarivelrealyfxIntegraoTab);
            app.IntegraoNumricaLabel.Text = 'Integração Numérica';
            app.DadosdafunoPanel_3 = uipanel(app.FunorealdevarivelrealyfxIntegraoTab);
            app.DadosdafunoPanel_3.Title = 'Dados da função';
            app.aLabel_2 = uilabel(app.DadosdafunoPanel_3);
            app.aLabel_2.Text = 'a';
            app.ea_2 = uieditfield(app.DadosdafunoPanel_3, 'numeric');
            app.hLabel_2 = uilabel(app.DadosdafunoPanel_3);
            app.hLabel_2.Text = 'n';
            app.en = uieditfield(app.DadosdafunoPanel_3, 'numeric');
            app.en.Value = 20;
            app.bLabel_3 = uilabel(app.DadosdafunoPanel_3);
            app.bLabel_3.Text = 'b';
            app.eb_2 = uieditfield(app.DadosdafunoPanel_3, 'numeric');
            app.eb_2.Value = 10;
            app.ftyLabel_2 = uilabel(app.DadosdafunoPanel_3);
            app.ftyLabel_2.Text = 'f(x) =';
            app.eFx_2 = uieditfield(app.DadosdafunoPanel_3, 'text');
            app.eFx_2.Value = 'sin(x)';
            app.bgIntegracao = uibuttongroup(app.FunorealdevarivelrealyfxIntegraoTab);
            app.bgIntegracao.Title = 'Métodos de Integração';
            app.rbRegraTrapezios = uiradiobutton(app.bgIntegracao);
            app.rbRegraTrapezios.Text = 'Regra dos Trapézios';
            app.rbRegraTrapezios.Value = true;
            app.rbRegraSimpson = uiradiobutton(app.bgIntegracao);
            app.rbRegraSimpson.Text = 'Regra de Simpson';
            app.rbTodos = uiradiobutton(app.bgIntegracao);
            app.rbTodos.Text = 'Todos';
            app.Atualizar_integracao = uibutton(app.FunorealdevarivelrealyfxIntegraoTab, 'push');
            app.Atualizar_integracao.ButtonPushedFcn = createCallbackFcn(app, @Atualizar_integracaoPushed, true);
            app.Atualizar_integracao.Text = 'Atualizar';
            app.UITable_integracao = uitable(app.FunorealdevarivelrealyfxIntegraoTab);
            app.UITable_integracao.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_integracao.RowName = {};
            app.bgGrafico = uibuttongroup(app.FunorealdevarivelrealyfxIntegraoTab);
            app.bgGrafico.Title = 'Gráfico';
            app.checkarea = uicheckbox(app.bgGrafico);
            app.checkarea.ValueChangedFcn = createCallbackFcn(app, @checkareaValueChanged, true);
            app.checkarea.Text = 'Área';
            app.GrelhaCheckBox = uicheckbox(app.bgGrafico);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.EixosCheckBox = uicheckbox(app.bgGrafico);
            app.EixosCheckBox.ValueChangedFcn = createCallbackFcn(app, @EixosCheckBoxValueChanged, true);
            app.EixosCheckBox.Text = 'Eixos';
            app.ResetButton_2 = uibutton(app.FunorealdevarivelrealyfxIntegraoTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.Funorealde2variveiszfxyTab = uitab(app.TabGroup);
            app.Funorealde2variveiszfxyTab.Title = 'Função real de 2 variáveis z = f(x,y)';
            app.UIAxes = uiaxes(app.Funorealde2variveiszfxyTab);
            title(app.UIAxes, 'Superficie')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_curvas = uiaxes(app.Funorealde2variveiszfxyTab);
            title(app.UIAxes_curvas, 'Curvas de nivel')
            xlabel(app.UIAxes_curvas, 'X')
            ylabel(app.UIAxes_curvas, 'Y')
            zlabel(app.UIAxes_curvas, 'Z')
            app.DadosdafunoPanel = uipanel(app.Funorealde2variveiszfxyTab);
            app.DadosdafunoPanel.Title = 'Dados da função';
            app.fxyEditFieldLabel = uilabel(app.DadosdafunoPanel);
            app.fxyEditFieldLabel.Text = 'f(x,y) =';
            app.eFxy = uieditfield(app.DadosdafunoPanel, 'text');
            app.eFxy.Value = 'x^2-y^2';
            app.aEditFieldLabel = uilabel(app.DadosdafunoPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdafunoPanel, 'numeric');
            app.eA.Value = -2;
            app.h1Label = uilabel(app.DadosdafunoPanel);
            app.h1Label.Text = 'h1';
            app.eH1 = uieditfield(app.DadosdafunoPanel, 'numeric');
            app.eH1.Value = 0.1;
            app.eB = uieditfield(app.DadosdafunoPanel, 'numeric');
            app.eB.Value = 2;
            app.bLabel = uilabel(app.DadosdafunoPanel);
            app.bLabel.Text = 'b';
            app.h2Label = uilabel(app.DadosdafunoPanel);
            app.h2Label.Text = 'h2';
            app.dLabel = uilabel(app.DadosdafunoPanel);
            app.dLabel.Text = 'd';
            app.eD = uieditfield(app.DadosdafunoPanel, 'numeric');
            app.eD.Value = 2;
            app.eH2 = uieditfield(app.DadosdafunoPanel, 'numeric');
            app.eH2.Value = 0.1;
            app.aEditFieldLabel_2 = uilabel(app.DadosdafunoPanel);
            app.aEditFieldLabel_2.Text = 'c';
            app.eC = uieditfield(app.DadosdafunoPanel, 'numeric');
            app.eC.Value = -2;
            app.bAtualizar = uibutton(app.Funorealde2variveiszfxyTab, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.Panel = uipanel(app.Funorealde2variveiszfxyTab);
            app.UIMostrador = uiaxes(app.Panel);
            app.UIMostrador.XTick = [];
            app.UIMostrador.YTick = [];
            app.LabelHarmonica = uilabel(app.Panel);
            app.LabelHarmonica.Text = 'A função não é harmónica';
            app.Panel_Parciais = uipanel(app.Funorealde2variveiszfxyTab);
            app.Panel_Parciais.Title = 'Derivadas Parciais';
            app.UIMostrador_2 = uiaxes(app.Panel_Parciais);
            app.UIMostrador_2.XTick = [];
            app.UIMostrador_2.YTick = [];
            app.ddDerivadasParciais = uidropdown(app.Panel_Parciais);
            app.ddDerivadasParciais.Items = {'Ordem a x', 'Ordem a y'};
            app.ddDerivadasParciais.ValueChangedFcn = createCallbackFcn(app, @ddDerivadasParciaisValueChanged, true);
            app.ddDerivadasParciais.Value = 'Ordem a x';
            app.ResetButton_3 = uibutton(app.Funorealde2variveiszfxyTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            % Show the figure after all components are created
            app.AM2_AT05.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AM2_AT05)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AM2_AT05)
        end
    end
end