classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TP_CR                          matlab.ui.Figure
        FicheiroMenu                   matlab.ui.container.Menu
        SairMenu                       matlab.ui.container.Menu
        SobreMenu                      matlab.ui.container.Menu
        AutoresMenu                    matlab.ui.container.Menu
        ClassificaodeumcasoPanel       matlab.ui.container.Panel
        ddRede_2                       matlab.ui.control.DropDown
        RedeDropDownLabel_2            matlab.ui.control.Label
        eBmi                           matlab.ui.control.NumericEditField
        BmiEditFieldLabel              matlab.ui.control.Label
        eGlucose                       matlab.ui.control.NumericEditField
        AvgglucoselevelEditFieldLabel  matlab.ui.control.Label
        ClassificaoLabel               matlab.ui.control.Label
        ClassificarButton              matlab.ui.control.Button
        eSmoking                       matlab.ui.control.NumericEditField
        SmokingstatusEditFieldLabel    matlab.ui.control.Label
        eResidence                     matlab.ui.control.NumericEditField
        ResidenceEditFieldLabel        matlab.ui.control.Label
        eMarried                       matlab.ui.control.NumericEditField
        EvermarriedEditFieldLabel      matlab.ui.control.Label
        eHeart                         matlab.ui.control.NumericEditField
        HeartdiseaseEditFieldLabel     matlab.ui.control.Label
        eHypertension                  matlab.ui.control.NumericEditField
        HypertensionEditFieldLabel     matlab.ui.control.Label
        eAge                           matlab.ui.control.NumericEditField
        AgeEditFieldLabel              matlab.ui.control.Label
        eGender                        matlab.ui.control.NumericEditField
        GenderEditFieldLabel           matlab.ui.control.Label
        MostradorAcerto                matlab.ui.control.UIAxes
        EscolherRedeTreinadaPanel      matlab.ui.container.Panel
        ddRede                         matlab.ui.control.DropDown
        RedeDropDownLabel              matlab.ui.control.Label
        eDatasetRede                   matlab.ui.control.DropDown
        DatasetDropDownLabel_2         matlab.ui.control.Label
        TestarButton                   matlab.ui.control.Button
        ResultadosPanel                matlab.ui.container.Panel
        TempoLabel                     matlab.ui.control.Label
        ErroLabel                      matlab.ui.control.Label
        PrecisoTotalLabel              matlab.ui.control.Label
        MostradorTempo                 matlab.ui.control.UIAxes
        MostradorErro                  matlab.ui.control.UIAxes
        MostradorPrecisao              matlab.ui.control.UIAxes
        CriareTreinarRedePanel         matlab.ui.container.Panel
        eN                             matlab.ui.control.EditField
        NmeroneurniocamadaLabel        matlab.ui.control.Label
        eDataset                       matlab.ui.control.DropDown
        DatasetDropDownLabel           matlab.ui.control.Label
        TreinarButton                  matlab.ui.control.Button
        eTeste                         matlab.ui.control.NumericEditField
        TesteEditFieldLabel            matlab.ui.control.Label
        eValidacao                     matlab.ui.control.NumericEditField
        ValidaoEditFieldLabel          matlab.ui.control.Label
        eTreino                        matlab.ui.control.NumericEditField
        TreinoEditFieldLabel           matlab.ui.control.Label
        SegmentaoLabel                 matlab.ui.control.Label
        ddAtivacaoS                    matlab.ui.control.DropDown
        FunoativaocamadaocultaLabel_2  matlab.ui.control.Label
        ddAtivacaoO                    matlab.ui.control.DropDown
        FunoativaocamadaocultaLabel    matlab.ui.control.Label
        ddTreino                       matlab.ui.control.DropDown
        FunotreinoDropDownLabel        matlab.ui.control.Label
        eC                             matlab.ui.control.NumericEditField
        NmerocamadasEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");
            if strcmp(SN,"Sim")
               delete(app.TP_CR);
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            img1 = imread('Martim Antunes.jpg');
            subplot(1,2,1); 
            imshow(img1);
            text(50, 50, 'Martim Antunes', 'Color', 'white', 'FontSize', 12);
            im2 = imread('Pedro Faneca.jpg');
            subplot(1,2,2);
            imshow(im2);
            text(50,50,'Pedro Faneca','Color','white','FontSize',12);
        end

        % Button pushed function: TreinarButton
        function TreinarButtonPushed(app, event)
            cla(app.MostradorPrecisao);
            cla(app.MostradorErro);
            cla(app.MostradorTempo);
            num_camadas = app.eC.Value;

            str_num_neunorios = app.eN.Value;

            funcao_treino = app.ddTreino.Value;

            funcao_ativacao_oculta = app.ddAtivacaoO.Value;

            funcao_ativacao_saida = app.ddAtivacaoS.Value;

            dataset=app.eDataset.Value;


            trainRatio = app.eTreino.Value;
            valRatio = app.eValidacao.Value;
            testRatio = app.eTeste.Value;


            erroCamadas = MException("MATLAB:bAtualizar:badC", ...
                                   "Introduza  um número > 0");


            num_neunorios =  str2num(str_num_neunorios);    

            erroTreino = MException("MATLAB:bAtualizar:badT", ...
                                   "Introduza  um número entre 0 e 1");

             erroValidacao = MException("MATLAB:bAtualizar:badV", ...
                                   "Introduza  um número entre 0 e 1");


             erroTeste = MException("MATLAB:bAtualizar:badT", ...
                                   "Introduza  um número entre 0 e 1");

             erroSoma =  MException("MATLAB:bAtualizar:badS", ...
                          "A soma dos 3 parâmetro de Ratio tem de ser 1");


             try
                 if isscalar(num_camadas) && isreal(num_camadas) && num_camadas > 0
                    app.eC.BackgroundColor = "w";
                else
                    app.eC.BackgroundColor = "r";
                    throw(erroCamadas);
                 end

                 if trainRatio < 0 || trainRatio > 1
                    app.eTreino.BackgroundColor = 'r';
                    throw(erroTreino)
                else
                    app.eTreino.BackgroundColor = 'w';
                end

                if valRatio < 0 || valRatio > 1
                    app.eValidacao.BackgroundColor = 'r';
                    throw(erroValidacao);
                  
                else
                    app.eValidacao.BackgroundColor = 'w';
                end

                if testRatio < 0 || testRatio > 1
                    app.eTeste.BackgroundColor = 'r';
                    throw(erroTeste);
        
                else
                    app.eTeste.BackgroundColor = 'w';
                end

                 if abs(trainRatio + valRatio + testRatio - 1) > eps
                    % Soma dos valores não é igual a 1
                    % Exiba uma mensagem de erro ou tome alguma ação adequada
                    app.eTreino.BackgroundColor = 'r';
                    app.eValidacao.BackgroundColor = 'r';
                    app.eTeste.BackgroundColor = 'r';
                    throw(erroSoma);

    
                 else
                    % Soma dos valores é igual a 1
                    % Faça o que precisa ser feito
                    app.eTreino.BackgroundColor = 'w';
                    app.eValidacao.BackgroundColor = 'w';
                    app.eTeste.BackgroundColor = 'w';
                 end

            
                 if strcmp(dataset, 'Start.csv')==0
                    data = readmatrix(dataset, 'Delimiter', ',', 'DecimalSeparator', '.');
                elseif strcmp(dataset, 'Train_Completo.csv')==0
                    data = readmatrix(dataset, 'Delimiter', ';', 'DecimalSeparator', '.');
                else
                % Adicione qualquer manipulação adicional de dados aqui para outros casos
                    data = readmatrix(dataset, 'Delimiter', ';', 'DecimalSeparator', '.');
                end
                 in =data(:,2:end-1)';
                 t=data(:,end)';

                tempoExecucao = tic;
                iteracoes=50;
                precisaoGlobal= [];
                erroMedio = [];
          
  
                for k=1:iteracoes
                     fprintf('Iteracao %d\n',k);
                     net = feedforwardnet(num_neunorios);
                     
                     net.trainFcn=funcao_treino;
                     
                     for i = 1:length(num_neunorios)
                        net.layers{i}.transferFcn = funcao_ativacao_oculta;
                     end
                     
                     net.layers{end}.transferFcn = funcao_ativacao_saida;
                        
                     net.divideParam.trainRatio = trainRatio;
                     net.divideParam.valRatio = valRatio;
                     net.divideParam.testRatio = testRatio;
    
                     net.trainParam.showWindow=0;
    
                     [net,tr] = train(net, in, t);  %TREINAR
    
    
                     out = sim(net, in);
    
                     out  = (out >= 0.5);
    
                     erro = perform(net,t,out);
    
                      precisao=0;
    
                     r = sum(out == t);
    
                     precisao = r/size(out,2) *100;

                     precisaoGlobal = [precisaoGlobal precisao];

                     erroMedio = [erroMedio erro];
    
                end
                
                precisaoGlobal = mean(precisaoGlobal);
                erroMedio = mean(erroMedio);
               
               precisao_str = num2str(precisaoGlobal);
               erro_str=num2str(erroMedio);
               tempo_str=num2str(toc(tempoExecucao));
    
               %Define o resultado na caixa de edição
               text(app.MostradorPrecisao,0.1,0.5,precisao_str,"Interpreter","latex","FontSize",15); 
               text(app.MostradorErro,0.1,0.5,erro_str,"Interpreter","latex","FontSize",15);
               text(app.MostradorTempo,0.1,0.5,tempo_str,"Interpreter","latex","FontSize",15); 
            
                catch ME
                    errordlg(ME.message,"ERRO","modal") 
                 end
        
        end

        % Callback function
        function bEscolherPushed(app, event)
            [fileName, pathName] = uigetfile('*.*', 'Carregar rede','./Redes/RedesGlobal/');
            if fileName == 0
            % O usuário cancelou a operação
                disp('Operação de abrir arquivo cancelada.');
            else
                % O usuário selecionou um arquivo para abrir
                %disp(['Arquivo selecionado: ', fullfile(pathName, fileName)]);
                % Aqui você pode adicionar o código para abrir e manipular o arquivo selecionado+
                filepath = fullfile(pathName, fileName);
               

               %text(app.MostradorRede,0.1,0.5,fileName,"Interpreter","latex","FontSize",15); 
            
            end
        end

        % Callback function
        function bEscolherDatasetButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*.*', 'Carregar dataset');
            if fileName == 0
            % O usuário cancelou a operação
                %disp('Operação de abrir arquivo cancelada.');
            else
                % O usuário selecionou um arquivo para abrir
                %disp(['Arquivo selecionado: ', fullfile(pathName, fileName)]);
                % Aqui você pode adicionar o código para abrir e manipular o arquivo selecionado+
                filepath = fullfile(pathName, fileName);

               text(app.MostradorDataset,0.1,0.5,fileName,"Interpreter","latex","FontSize",15); 
            
            end
        end

        % Button pushed function: TestarButton
        function TestarButtonPushed(app, event)
            cla(app.MostradorPrecisao);
            cla(app.MostradorErro);
            cla(app.MostradorTempo);
            
            dataset=app.eDatasetRede.Value;
            rede=load(fullfile('Redes', 'RedesGlobal', app.ddRede.Value));


            if strcmp(dataset, 'Start.csv')
                data = readmatrix(dataset, 'Delimiter', ',', 'DecimalSeparator', '.');
            elseif strcmp(dataset, 'Train_Completo.csv')
                data = readmatrix(dataset, 'Delimiter', ';', 'DecimalSeparator', '.');
            else
               data = readmatrix(dataset, 'Delimiter', ';', 'DecimalSeparator', '.');
            end

            in =data(:,2:end-1)';
            t=data(:,end)';
            tempoExecucao = tic;

            net=rede.net;

            out = sim(net, in);

            out = (out >= 0.5);
            
            erro = perform(net,t,out);  %desempenho rede
            
            precisaoTotal = 0;
            
            %Calcular precisao Total
            r = sum(out == t);

            precisaoTotal = r/size(out,2) *100;

            precisao_str = num2str(precisaoTotal);
            erro_str=num2str(erro);
            tempo_str=num2str(toc(tempoExecucao));
    
             %Define o resultado na caixa de edição
             text(app.MostradorPrecisao,0.1,0.5,precisao_str,"Interpreter","latex","FontSize",15); 
             text(app.MostradorErro,0.1,0.5,erro_str,"Interpreter","latex","FontSize",15);
             text(app.MostradorTempo,0.1,0.5,tempo_str,"Interpreter","latex","FontSize",15); 


        end

        % Button pushed function: ClassificarButton
        function ClassificarButtonPushed(app, event)
            
            cla(app.MostradorAcerto);

            gender=app.eGender.Value;
            age=app.eAge.Value;
            hypertension = app.eHypertension.Value;
            heart_disease = app.eHeart.Value;
            married = app.eMarried.Value;
            residence = app.eResidence.Value;
            glucose = app.eGlucose.Value;
            bmi = app.eBmi.Value;
            smoking = app.eSmoking.Value;

            rede=load(fullfile('Redes', 'RedesGlobal', app.ddRede_2.Value));


             erroGender = MException("MATLAB:bAtualizar:badG", ...
                                   "Introduza 0 ou 1");

             erroaAge = MException("MATLAB:bAtualizar:badA", ...
                                   "Introduza um número >0");

             erroHypertension = MException("MATLAB:bAtualizar:badH", ...
                                   "Introduza 0 ou 1");

             erroHeart = MException("MATLAB:bAtualizar:badH", ...
                                   "Introduza 0 ou 1");

             erroMarried = MException("MATLAB:bAtualizar:badG", ...
                                   "Introduza 0 ou 1");

             erroResidence = MException("MATLAB:bAtualizar:badR", ...
                                   "Introduza 0 ou 1");

             erroGlucose = MException("MATLAB:bAtualizar:badG", ...
                                   "Introduza  um número >0");


             erroBmi = MException("MATLAB:bAtualizar:badB", ...
                                   "Introduza um número >0");

             
            erroSmoking = MException("MATLAB:bAtualizar:badG", ...
                                   "Introduza 0,1,2 ou 3");

            try
                 if gender == 0 || gender == 1
                    app.eGender.BackgroundColor = 'w'; % Correto
                else
                    app.eGender.BackgroundColor = 'r'; % Errado
                    throw(erroGender);
                end
            
                if age > 0
                    app.eAge.BackgroundColor = 'w'; % Correto
                else
                    app.eAge.BackgroundColor = 'r'; % Errado
                    throw(erroAge);
                end
            
                if hypertension == 0 || hypertension == 1
                    app.eHypertension.BackgroundColor = 'w'; % Correto
                else
                    app.eHypertension.BackgroundColor = 'r'; % Errado
                    throw(erroHypertension);
                end
            
                if heart_disease == 0 || heart_disease == 1
                    app.eHeart.BackgroundColor = 'w'; % Correto
                else
                    app.eHeart.BackgroundColor = 'r'; % Errado
                    throw(erroHeart);
                end
            
                if married == 0 || married == 1
                    app.eMarried.BackgroundColor = 'w'; % Correto
                else
                    app.eMarried.BackgroundColor = 'r'; % Errado
                    throw(erroMarried);
                end
            
                if residence == 0 || residence == 1
                    app.eResidence.BackgroundColor = 'w'; % Correto
                else
                    app.eResidence.BackgroundColor = 'r'; % Errado
                    throw(erroResidence);
                end
            
                if glucose > 0
                    app.eGlucose.BackgroundColor = 'w'; % Correto
                else
                    app.eGlucose.BackgroundColor = 'r'; % Errado
                    throw(erroGlucose);
                end
            
                if bmi > 0
                    app.eBmi.BackgroundColor = 'w'; % Correto
                else
                    app.eBmi.BackgroundColor = 'r'; % Errado
                    throw(erroBmi);
                end
            
                if smoking == 0 || smoking == 1 || smoking == 2 || smoking == 3
                    app.eSmoking.BackgroundColor = 'w'; % Correto
                else
                    app.eSmoking.BackgroundColor = 'r'; % Errado
                    throw(erroSmoking);
                end

            catch ME
                errordlg(ME.message,"ERRO","modal") 
            end

            in = [gender, age, hypertension, heart_disease, married, residence, glucose, bmi, smoking]';
            net=rede.net;
            out = sim(net, in);
            stroke=0;
            
            if out >= 0.5
                stroke = 1;
            else
                stroke = 0;

            end

            text(app.MostradorAcerto,0.1,0.5,num2str(stroke),"Interpreter","latex","FontSize",15); 

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TP_CR = uifigure('Visible', 'off');
            app.TP_CR.Name = 'MATLAB App';
            app.FicheiroMenu = uimenu(app.TP_CR);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.SairMenu = uimenu(app.FicheiroMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.TP_CR);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.CriareTreinarRedePanel = uipanel(app.TP_CR);
            app.CriareTreinarRedePanel.Title = 'Criar e Treinar Rede';
            app.NmerocamadasEditFieldLabel = uilabel(app.CriareTreinarRedePanel);
            app.NmerocamadasEditFieldLabel.Text = 'Número camadas';
            app.eC = uieditfield(app.CriareTreinarRedePanel, 'numeric');
            app.FunotreinoDropDownLabel = uilabel(app.CriareTreinarRedePanel);
            app.FunotreinoDropDownLabel.Text = 'Função treino';
            app.ddTreino = uidropdown(app.CriareTreinarRedePanel);
            app.ddTreino.Items = {'trainlm', 'trainbfgc', 'trainbr', 'trainbu', 'trainc', 'traincgb', 'traincgf ', 'traincgp ', 'traingd ', 'traingda ', 'traingdm ', 'traingdx', 'trainbfg', 'trainoss ', 'trainr', 'trains '};
            app.ddTreino.Value = 'trainlm';
            app.FunoativaocamadaocultaLabel = uilabel(app.CriareTreinarRedePanel);
            app.FunoativaocamadaocultaLabel.Text = 'Função ativação (camada oculta)';
            app.ddAtivacaoO = uidropdown(app.CriareTreinarRedePanel);
            app.ddAtivacaoO.Items = {'tansig', 'compet', 'hardlim', 'hardlims ', 'logsig', 'netinv ', 'poslin', 'purelin', 'radbas', 'radbasn ', 'satlin ', 'satlins', 'softmax', 'tribas'};
            app.ddAtivacaoO.Value = 'tansig';
            app.FunoativaocamadaocultaLabel_2 = uilabel(app.CriareTreinarRedePanel);
            app.FunoativaocamadaocultaLabel_2.Text = 'Função ativação (camada saida)';
            app.ddAtivacaoS = uidropdown(app.CriareTreinarRedePanel);
            app.ddAtivacaoS.Items = {'tansig', 'compet', 'hardlim', 'hardlims ', 'logsig', 'netinv ', 'poslin', 'purelin', 'radbas', 'radbasn ', 'satlin ', 'satlins', 'softmax', 'tribas'};
            app.ddAtivacaoS.Value = 'purelin';
            app.SegmentaoLabel = uilabel(app.CriareTreinarRedePanel);
            app.SegmentaoLabel.Text = 'Segmentação';
            app.TreinoEditFieldLabel = uilabel(app.CriareTreinarRedePanel);
            app.TreinoEditFieldLabel.Text = 'Treino';
            app.eTreino = uieditfield(app.CriareTreinarRedePanel, 'numeric');
            app.eTreino.Value = 0.7;
            app.ValidaoEditFieldLabel = uilabel(app.CriareTreinarRedePanel);
            app.ValidaoEditFieldLabel.Text = 'Validação';
            app.eValidacao = uieditfield(app.CriareTreinarRedePanel, 'numeric');
            app.eValidacao.Value = 0.15;
            app.TesteEditFieldLabel = uilabel(app.CriareTreinarRedePanel);
            app.TesteEditFieldLabel.Text = 'Teste';
            app.eTeste = uieditfield(app.CriareTreinarRedePanel, 'numeric');
            app.eTeste.Value = 0.15;
            app.TreinarButton = uibutton(app.CriareTreinarRedePanel, 'push');
            app.TreinarButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarButtonPushed, true);
            app.TreinarButton.Text = 'Treinar';
            app.DatasetDropDownLabel = uilabel(app.CriareTreinarRedePanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.eDataset = uidropdown(app.CriareTreinarRedePanel);
            app.eDataset.Items = {'Start.csv', 'Train_Completo.csv', 'Test.csv', 'Option 4'};
            app.eDataset.Value = 'Train_Completo.csv';
            app.NmeroneurniocamadaLabel = uilabel(app.CriareTreinarRedePanel);
            app.NmeroneurniocamadaLabel.Text = 'Número neurónio/camada';
            app.eN = uieditfield(app.CriareTreinarRedePanel, 'text');
            app.ResultadosPanel = uipanel(app.TP_CR);
            app.ResultadosPanel.Title = 'Resultados';
            app.MostradorPrecisao = uiaxes(app.ResultadosPanel);
            app.MostradorPrecisao.XTick = [];
            app.MostradorPrecisao.YTick = [];
            app.MostradorErro = uiaxes(app.ResultadosPanel);
            app.MostradorErro.XTick = [];
            app.MostradorErro.YTick = [];
            app.MostradorTempo = uiaxes(app.ResultadosPanel);
            app.MostradorTempo.XTick = [];
            app.MostradorTempo.YTick = [];
            app.PrecisoTotalLabel = uilabel(app.ResultadosPanel);
            app.PrecisoTotalLabel.Text = 'Precisão Total';
            app.ErroLabel = uilabel(app.ResultadosPanel);
            app.ErroLabel.Text = 'Erro';
            app.TempoLabel = uilabel(app.ResultadosPanel);
            app.TempoLabel.Text = 'Tempo';
            app.EscolherRedeTreinadaPanel = uipanel(app.TP_CR);
            app.EscolherRedeTreinadaPanel.Title = 'Escolher Rede Treinada';
            app.TestarButton = uibutton(app.EscolherRedeTreinadaPanel, 'push');
            app.TestarButton.ButtonPushedFcn = createCallbackFcn(app, @TestarButtonPushed, true);
            app.TestarButton.Text = 'Testar';
            app.DatasetDropDownLabel_2 = uilabel(app.EscolherRedeTreinadaPanel);
            app.DatasetDropDownLabel_2.Text = 'Dataset';
            app.eDatasetRede = uidropdown(app.EscolherRedeTreinadaPanel);
            app.eDatasetRede.Items = {'Start.csv', 'Train_Completo.csv', 'Test.csv'};
            app.eDatasetRede.Value = 'Test.csv';
            app.RedeDropDownLabel = uilabel(app.EscolherRedeTreinadaPanel);
            app.RedeDropDownLabel.Text = 'Rede';
            app.ddRede = uidropdown(app.EscolherRedeTreinadaPanel);
            app.ddRede.Items = {'rede1.mat', 'rede2.mat', 'rede3.mat', 'rede4.mat', 'rede5.mat', 'rede6.mat', 'rede7.mat', 'rede8.mat', 'rede9.mat', 'rede10.mat', 'rede11.mat', 'rede12.mat', 'rede13.mat', 'rede14.mat', 'rede15.mat', 'rede16.mat', 'rede17.mat', 'rede18.mat', 'rede19.mat', 'rede20.mat', 'rede21.mat'};
            app.ddRede.Value = 'rede17.mat';
            app.ClassificaodeumcasoPanel = uipanel(app.TP_CR);
            app.ClassificaodeumcasoPanel.Title = 'Classificação de um caso';
            app.MostradorAcerto = uiaxes(app.ClassificaodeumcasoPanel);
            app.MostradorAcerto.XTick = [];
            app.MostradorAcerto.YTick = [];
            app.GenderEditFieldLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.GenderEditFieldLabel.Text = 'Gender';
            app.eGender = uieditfield(app.ClassificaodeumcasoPanel, 'numeric');
            app.eGender.Value = 1;
            app.AgeEditFieldLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.eAge = uieditfield(app.ClassificaodeumcasoPanel, 'numeric');
            app.eAge.Value = 79;
            app.HypertensionEditFieldLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.HypertensionEditFieldLabel.Text = 'Hypertension';
            app.eHypertension = uieditfield(app.ClassificaodeumcasoPanel, 'numeric');
            app.eHypertension.Value = 1;
            app.HeartdiseaseEditFieldLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.HeartdiseaseEditFieldLabel.Text = 'Heart disease';
            app.eHeart = uieditfield(app.ClassificaodeumcasoPanel, 'numeric');
            app.EvermarriedEditFieldLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.EvermarriedEditFieldLabel.Text = 'Ever married';
            app.eMarried = uieditfield(app.ClassificaodeumcasoPanel, 'numeric');
            app.eMarried.Value = 1;
            app.ResidenceEditFieldLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.ResidenceEditFieldLabel.Text = 'Residence';
            app.eResidence = uieditfield(app.ClassificaodeumcasoPanel, 'numeric');
            app.SmokingstatusEditFieldLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.SmokingstatusEditFieldLabel.Text = 'Smoking status';
            app.eSmoking = uieditfield(app.ClassificaodeumcasoPanel, 'numeric');
            app.ClassificarButton = uibutton(app.ClassificaodeumcasoPanel, 'push');
            app.ClassificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificarButtonPushed, true);
            app.ClassificarButton.Text = 'Classificar';
            app.ClassificaoLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.ClassificaoLabel.Text = 'Classificação';
            app.AvgglucoselevelEditFieldLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.AvgglucoselevelEditFieldLabel.Text = 'Avg glucose level';
            app.eGlucose = uieditfield(app.ClassificaodeumcasoPanel, 'numeric');
            app.eGlucose.Value = 174.12;
            app.BmiEditFieldLabel = uilabel(app.ClassificaodeumcasoPanel);
            app.BmiEditFieldLabel.Text = 'Bmi';
            app.eBmi = uieditfield(app.ClassificaodeumcasoPanel, 'numeric');
            app.eBmi.Value = 24;
            app.RedeDropDownLabel_2 = uilabel(app.ClassificaodeumcasoPanel);
            app.RedeDropDownLabel_2.Text = 'Rede';
            app.ddRede_2 = uidropdown(app.ClassificaodeumcasoPanel);
            app.ddRede_2.Items = {'rede1.mat', 'rede2.mat', 'rede3.mat', 'rede4.mat', 'rede5.mat', 'rede6.mat', 'rede7.mat', 'rede8.mat', 'rede9.mat', 'rede10.mat', 'rede11.mat', 'rede12.mat', 'rede13.mat', 'rede14.mat', 'rede15.mat', 'rede16.mat', 'rede17.mat', 'rede18.mat', 'rede19.mat', 'rede20.mat', 'rede21.mat'};
            app.ddRede_2.Value = 'rede17.mat';
            % Show the figure after all components are created
            app.TP_CR.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TP_CR)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TP_CR)
        end
    end
end